classdef MatlabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoiseAugmentLabel              matlab.ui.control.Label
        DatasetsPanel                  matlab.ui.container.Panel
        SpeakerPositionEditField2      matlab.ui.control.NumericEditField
        SpeakerPositionEditField3      matlab.ui.control.NumericEditField
        NoiseEditField2                matlab.ui.control.NumericEditField
        NoiseEditField3                matlab.ui.control.NumericEditField
        Noise2EditField2               matlab.ui.control.NumericEditField
        Noise2EditField3               matlab.ui.control.NumericEditField
        Noise3EditField2               matlab.ui.control.NumericEditField
        Noise3EditField3               matlab.ui.control.NumericEditField
        Noise4EditField2               matlab.ui.control.NumericEditField
        Noise4EditField3               matlab.ui.control.NumericEditField
        XmLabel_2                      matlab.ui.control.Label
        YmLabel_2                      matlab.ui.control.Label
        ZmLabel_2                      matlab.ui.control.Label
        SpeakerPath                    matlab.ui.control.EditField
        PathLabel                      matlab.ui.control.Label
        NoisePath                      matlab.ui.control.EditField
        Noise2Path                     matlab.ui.control.EditField
        Noise3Path                     matlab.ui.control.EditField
        Noise4Path                     matlab.ui.control.EditField
        ProbabilityLabel               matlab.ui.control.Label
        NoiseProbability               matlab.ui.control.NumericEditField
        Noise2Probability              matlab.ui.control.NumericEditField
        Noise3Probability              matlab.ui.control.NumericEditField
        Noise4Probability              matlab.ui.control.NumericEditField
        AmplitudeNormalizedtoSpeakerLabel  matlab.ui.control.Label
        NoiseAmp                       matlab.ui.control.NumericEditField
        Noise2Amp                      matlab.ui.control.NumericEditField
        Noise3Amp                      matlab.ui.control.NumericEditField
        Noise4Amp                      matlab.ui.control.NumericEditField
        SpeakerEditFieldLabel          matlab.ui.control.Label
        SpeakerEditField               matlab.ui.control.NumericEditField
        Noise1EditFieldLabel           matlab.ui.control.Label
        Noise1EditField                matlab.ui.control.NumericEditField
        Noise2EditField                matlab.ui.control.NumericEditField
        Noise3EditField                matlab.ui.control.NumericEditField
        Noise4EditField                matlab.ui.control.NumericEditField
        Noise5EditField2               matlab.ui.control.NumericEditField
        Noise5EditField3               matlab.ui.control.NumericEditField
        Noise5Path                     matlab.ui.control.EditField
        Noise5Probability              matlab.ui.control.NumericEditField
        Noise5Amp                      matlab.ui.control.NumericEditField
        Noise5EditField                matlab.ui.control.NumericEditField
        NumberofNoiseSourcesDropDownLabel  matlab.ui.control.Label
        NumberofNoiseSourcesDropDown   matlab.ui.control.DropDown
        Noise2EditFieldLabel           matlab.ui.control.Label
        Noise3EditFieldLabel           matlab.ui.control.Label
        Noise4EditFieldLabel           matlab.ui.control.Label
        Noise5EditFieldLabel           matlab.ui.control.Label
        DesiredSamplingRateKHzLabel    matlab.ui.control.Label
        DesiredSamplingRate            matlab.ui.control.NumericEditField
        WhiteNoiseCheckBox             matlab.ui.control.CheckBox
        PinkNoiseCheckBox              matlab.ui.control.CheckBox
        WhiteNoiseEditFieldLabel       matlab.ui.control.Label
        BrownianNoiseEditFieldLabel    matlab.ui.control.Label
        WhiteNoiseSNR                  matlab.ui.control.NumericEditField
        PinkNoiseSNR                   matlab.ui.control.NumericEditField
        WhiteNoiseSNRLabel             matlab.ui.control.Label
        PinkNoiseSNRLabel              matlab.ui.control.Label
        BrownianNoiseCheckBox          matlab.ui.control.CheckBox
        PinkNoiseEditFieldLabel        matlab.ui.control.Label
        BrownianNoiseSNR               matlab.ui.control.NumericEditField
        BrownianNoiseSNRLabel          matlab.ui.control.Label
        SpeakerRandomEditField         matlab.ui.control.NumericEditField
        SpeakerRandomEditField2        matlab.ui.control.NumericEditField
        SpeakerRandomEditField3        matlab.ui.control.NumericEditField
        Noise1RandomEditField          matlab.ui.control.NumericEditField
        Noise1RandomEditField2         matlab.ui.control.NumericEditField
        Noise1RandomEditField3         matlab.ui.control.NumericEditField
        Noise2RandomEditField          matlab.ui.control.NumericEditField
        Noise2RandomEditField2         matlab.ui.control.NumericEditField
        Noise2RandomEditField3         matlab.ui.control.NumericEditField
        Noise3RandomEditField          matlab.ui.control.NumericEditField
        Noise3RandomEditField2         matlab.ui.control.NumericEditField
        Noise3RandomEditField3         matlab.ui.control.NumericEditField
        Noise4RandomEditField          matlab.ui.control.NumericEditField
        Noise4RandomEditField2         matlab.ui.control.NumericEditField
        Noise4RandomEditField3         matlab.ui.control.NumericEditField
        Noise5RandomEditField          matlab.ui.control.NumericEditField
        Noise5RandomEditField2         matlab.ui.control.NumericEditField
        Noise5RandomEditField3         matlab.ui.control.NumericEditField
        RandomCheckBox                 matlab.ui.control.CheckBox
        RandomCheckBox1                matlab.ui.control.CheckBox
        RandomCheckBox2                matlab.ui.control.CheckBox
        RandomCheckBox3                matlab.ui.control.CheckBox
        RandomCheckBox4                matlab.ui.control.CheckBox
        RandomCheckBox5                matlab.ui.control.CheckBox
        RandomLabel                    matlab.ui.control.Label
        RandomLabel1                   matlab.ui.control.Label
        RandomLabel2                   matlab.ui.control.Label
        RandomLabel3                   matlab.ui.control.Label
        RandomLabel4                   matlab.ui.control.Label
        RandomLabel5                   matlab.ui.control.Label
        MovingCheckBox                 matlab.ui.control.CheckBox
        MovingLabel                    matlab.ui.control.Label
        MovingConfigPath               matlab.ui.control.EditField
        MovingConfigLabel              matlab.ui.control.Label
        SpeechOrderCheckBox            matlab.ui.control.CheckBox
        UsesourcetextfileinsteadofpathLabel  matlab.ui.control.Label
        SpeechOrderTextBox             matlab.ui.control.EditField
        NoiseAmp_2                     matlab.ui.control.NumericEditField
        Noise2Amp_2                    matlab.ui.control.NumericEditField
        Noise3Amp_2                    matlab.ui.control.NumericEditField
        Noise4Amp_2                    matlab.ui.control.NumericEditField
        Noise5Amp_2                    matlab.ui.control.NumericEditField
        OutputPanel                    matlab.ui.container.Panel
        GenerateDatasetButton          matlab.ui.control.Button
        OutputPath                     matlab.ui.control.EditField
        OutputPathLabel                matlab.ui.control.Label
        NamingConv                     matlab.ui.control.EditField
        NamingConventionLabel          matlab.ui.control.Label
        ProgressTextAreaLabel          matlab.ui.control.Label
        ProgressTextArea               matlab.ui.control.TextArea
        InputFilenameAsOutputFilenameLabel  matlab.ui.control.Label
        InputFilenameAsOutputFilename  matlab.ui.control.CheckBox
        VisualizationofRoomPanel       matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RoomConfigurationPanel         matlab.ui.container.Panel
        XmLabel                        matlab.ui.control.Label
        YmLabel                        matlab.ui.control.Label
        ZmLabel                        matlab.ui.control.Label
        RoomDimensionsEditField2       matlab.ui.control.NumericEditField
        RoomDimensionsEditField3       matlab.ui.control.NumericEditField
        Microphone1PositionEditField2  matlab.ui.control.NumericEditField
        Microphone1PositionEditField3  matlab.ui.control.NumericEditField
        Microphone2PositionEditField2  matlab.ui.control.NumericEditField
        Microphone2PositionEditField3  matlab.ui.control.NumericEditField
        Microphone2EditField           matlab.ui.control.NumericEditField
        Microphone3PositionEditField2  matlab.ui.control.NumericEditField
        Microphone3PositionEditField3  matlab.ui.control.NumericEditField
        Microphone3EditFieldLabel      matlab.ui.control.Label
        Microphone3EditField           matlab.ui.control.NumericEditField
        Microphone4PositionEditField2  matlab.ui.control.NumericEditField
        Microphone4PositionEditField3  matlab.ui.control.NumericEditField
        Microphone4EditFieldLabel      matlab.ui.control.Label
        Microphone4EditField           matlab.ui.control.NumericEditField
        Microphone5PositionEditField2  matlab.ui.control.NumericEditField
        Microphone5PositionEditField3  matlab.ui.control.NumericEditField
        Microphone5EditFieldLabel      matlab.ui.control.Label
        Microphone5EditField           matlab.ui.control.NumericEditField
        Microphone2EditFieldLabel      matlab.ui.control.Label
        RoomDimensionsEditFieldLabel   matlab.ui.control.Label
        RoomDimensionsEditField        matlab.ui.control.NumericEditField
        Microphone1EditFieldLabel      matlab.ui.control.Label
        Microphone1EditField           matlab.ui.control.NumericEditField
        NumberofMicrophonesDropDownLabel  matlab.ui.control.Label
        NumberofMicrophonesDropDown    matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function AugmentDataset(app, paths, positions, noise_settings, other_noise, goal_freq)% snr_amp)
            
            % INPUTS:
            % paths - path to input desired speaker sound file
            dataset_path = paths{1};
            noise_paths = paths{2};
            output_path = paths{3};
            name_conv = paths{4};
            % paths{5} (optional) - text file listing files to augment
            name_same = app.InputFilenameAsOutputFilename.Value;
            
            % positions - positions of source, noise source(s), microphone(s), and room dimensions
            source_pos = positions{1};
            noise_pos = positions{2};
            mic_pos = positions{3};
            room_dims = positions{4};

            % noise_settings - probability and amplitude scaling factor for each noise source
            noise_prob = noise_settings{1};
            amp = noise_settings{2};
            
            % other_noise - option to add white/pink/brownian noise
            % goal_freq - desired output frequency in KHz (gets converted to Hz)
            goal_freq = goal_freq * 1000;

            % exclusive - specific to LibriSpeech, this argument ensures that the desired speakers
            %             and background noise speakers are different

            % snr_amp - if given, the combined noise file is scaled relative to the
            %           input speech amplitude using this scaling factor
            
            rng('shuffle');

            %set exclusive variable
            if ~exist('exclusive','var')
                % third parameter does not exist, so default it to something
                exclusive = 0;
            end
            
            %configure microphones here!
            addpath('Roomsimove/');
            addpath('Logging/');
            % edit Roomsimove/room_config.txt
            rewrite_config(mic_pos, room_dims);
            conf_file = './Logging/room_config.txt';
            
            %write information to log file
            write_info(output_path, dataset_path, noise_paths, noise_prob, amp, source_pos, noise_pos, mic_pos, room_dims);
            C =  {"Output File","Input File", "x", "y", "z"};
            for i=1:length(noise_paths)
                C{(i)*4+2} = strcat("Noise File ",int2str(i));
                C{(i)*4+3} = "x";
                C{(i)*4+4} = "y";
                C{(i)*4+5} = "z";
            end
            s = strcat(output_path,'log.xls');
            writecell(C,s);
            
            % compute RIR once in non-random case
            if length(source_pos) == 3
                H1 = roomsimove_single(conf_file, source_pos);
                source_pos_temp = source_pos;
            end
            
            for i=1:length(noise_paths) % could be an issue for this to all be loaded in memory at once
                noise_Files{i}(:) = [dir(fullfile(noise_paths(i),'*.wav')), dir(fullfile(noise_paths(i),'*.flac')),
                             dir(fullfile(noise_paths(i),'*.ogg')), dir(fullfile(noise_paths(i),'*.au')),
                             dir(fullfile(noise_paths(i),'*.aiff')), dir(fullfile(noise_paths(i),'*.aif')),
                             dir(fullfile(noise_paths(i),'*.aifc')), dir(fullfile(noise_paths(i),'*.m4a')),
                             dir(fullfile(noise_paths(i),'*.mp3')), dir(fullfile(noise_paths(i),'*.mp4'))]; %gets all wav files in directory
        
                if length(noise_pos{i}) == 3
                    H2{i} = roomsimove_single(conf_file, noise_pos{i});
                    noise_pos_temp{i} = noise_pos{i};
                elseif length(noise_pos{i}) == 6
                    H2{i} = [];
                    noise_pos_temp{i} = [0; 0; 0;];
                end
            end
            if length(paths) == 4 % augment all files in dataset path
                dataset_Files = [dir(fullfile(dataset_path,'*.wav')), dir(fullfile(dataset_path,'*.flac')),
                             dir(fullfile(dataset_path,'*.ogg')), dir(fullfile(dataset_path,'*.au')),
                             dir(fullfile(dataset_path,'*.aiff')), dir(fullfile(dataset_path,'*.aif')),
                             dir(fullfile(dataset_path,'*.aifc')), dir(fullfile(dataset_path,'*.m4a')),
                             dir(fullfile(dataset_path,'*.mp3')), dir(fullfile(dataset_path,'*.mp4'))]; %gets all wav files in directory
            elseif length(paths) == 5 % augment all files listed in text file
                srcfile = paths{5};
                fid=fopen(srcfile);
                tline = fgetl(fid);
                while ischar(tline)
                    if  ~(exist("dataset_Files"))
                        dataset_Files{1} = tline;
                    else
                        dataset_Files{end+1} = tline;
                    end
                    tline = fgetl(fid);
                end
                fclose(fid);
            end
            
            for k = 1:length(dataset_Files)
                clear noise_filename noise_index;
                if length(paths) == 4
                    dataset_filename = dataset_Files(k).name;
                elseif length(paths) == 5
                    dataset_filename = dataset_Files{k};
                end
                
                % specifically for LibriSpeech, make speaker exclusive
                if exclusive
                    baseFileNameOne = dataset_filename;
                    reader = split(baseFileNameOne, "-");
                    reader_ID_one = char(reader(1));
                end
                    
                %if random speaker position
                if length(source_pos) == 6
                    if source_pos(1) > source_pos(4)
                        x = unifrnd(source_pos(4), source_pos(1));
                    else
                        x = unifrnd(source_pos(1), source_pos(4));
                    end
                    if source_pos(2) > source_pos(5)
                        y = unifrnd(source_pos(5), source_pos(2));
                    else
                        y = unifrnd(source_pos(2), source_pos(5));
                    end
                    if source_pos(3) > source_pos(6)
                        z = unifrnd(source_pos(6), source_pos(3));
                    else
                        z = unifrnd(source_pos(3), source_pos(6));
                    end
                    H1 = roomsimove_single(conf_file, [x; y; z;]);
                    source_pos_temp = [x; y; z;];
                end
                    
                %select necessary noise files
                noise_count = 1;
                for i=1:length(noise_paths)
                    rand_prob = unifrnd(0,1);
                    if rand_prob < noise_prob(i) 
                        %add noise of this type
                        noise_ind = randi([1 length(noise_Files{i})]);
                        baseFileName = noise_Files{i}(noise_ind).name;
                        
                        % specifically for LibriSpeech, make speaker exclusive
                        if exclusive
                            reader = split(baseFileName, "-");
                            reader_ID = char(reader(1));
                            %enforce exlusive
                            while strcmp(reader_ID, reader_ID_one) == 1
                                noise_ind = randi([1 length(noise_Files{i})]);
                                baseFileName = noise_Files{i}(noise_ind).name;
                                reader = split(baseFileName, "-");
                                reader_ID = char(reader(1));
                            end
                        end
                        
                        noise_filename(noise_count) = strcat(noise_paths(i), baseFileName);
                        noise_index(noise_count) = i;
                            
                        %if random noise position
                        if length(noise_pos{i}) == 6
                            if noise_pos{i}(1) > noise_pos{i}(4)
                                x = unifrnd(noise_pos{i}(4), noise_pos{i}(1));
                            else
                                x = unifrnd(noise_pos{i}(1), noise_pos{i}(4));
                            end
                            if noise_pos{i}(2) > noise_pos{i}(5)
                                y = unifrnd(noise_pos{i}(5), noise_pos{i}(2));
                            else
                                y = unifrnd(noise_pos{i}(2), noise_pos{i}(5));
                            end
                            if noise_pos{i}(3) > noise_pos{i}(6)
                                z = unifrnd(noise_pos{i}(6), noise_pos{i}(3));
                            else
                                z = unifrnd(noise_pos{i}(3), noise_pos{i}(6));
                            end
                            H2{i} = roomsimove_single('room_sensor_config_cellphone_MJ.txt', [x; y; z;]);
                            noise_pos_temp{i} = [x; y; z;];
                        end
                        noise_count = noise_count + 1;
                    end
                end
                %augment file
                
                % set values as empty if no noise sources for this input
                if ~(exist("noise_filename"))
        	        noise_filename = [];
                    noise_index = [];
        	    end 
                if ~(exist("H2"))
                    H2 = [];
                    noise_pos_temp = [];
                end    
                    
                %augment_file(dataset_filename, noise_filename, H1, H2, noise_index, dataset_path, output_path, name_conv, name_same, k, amp, other_noise, goal_freq,source_pos_temp, noise_pos_temp);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % name_conv - naming convention (i.e. the files will be named {name_conv}_#.wav)
                % name_same - if name_same is 1, the output filenames will be the same as the input
                %             desired speaker filenames
                if name_same == 1
                    out_path = strcat(output_path, dataset_filename);
                    s = dataset_filename;
                else
                    out_path = strcat(output_path, name_conv, " ", int2str(k), '.wav');
                    s = strcat(name_conv, " ", int2str(k), '.wav');
                end
                
                % dataset_fn is the full path of the desired speech file
                dataset_fn = strcat(dataset_path, dataset_filename);
        
                %augment sound file
                augment_file(dataset_fn, noise_filename, H1, H2, noise_index, out_path, amp, other_noise, goal_freq);
        
                %add to log file
                C =  {s, dataset_fn, source_pos_temp(1), source_pos_temp(2), source_pos_temp(3)};
                noise_count = 1;
                for i=1:max(noise_index)
                    if sum(ismember(i,noise_index)) == 0
                        C{(i)*4+2} = "";
                        C{(i)*4+3} = "";
                        C{(i)*4+4} = "";
                        C{(i)*4+5} = "";
                    else
                        C{(i)*4+2} = noise_filename(noise_count);
                        n = noise_pos_temp{i};
                        C{(i)*4+3} = n(1);
                        C{(i)*4+4} = n(2);
                        C{(i)*4+5} = n(3);
                        noise_count = noise_count + 1;
                    end
                end
                writecell(C,strcat(output_path,'log.xls'),'WriteMode','append')
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                %write iteration number to gui
                s = strcat("Iteration: ", int2str(k), '/' , int2str(length(dataset_Files)));
                app.ProgressTextArea.Value = s;
                drawnow;
            end
        end
        
        function updateGUI(app)
            value = app.RoomDimensionsEditField.Value;
            value2 = app.RoomDimensionsEditField2.Value;
            value3 = app.RoomDimensionsEditField3.Value;
            [x y] = meshgrid(0:0.5:value,0:0.5:value2);
            z = 0.*x + 0.*y;
            hsurf = surf(app.UIAxes, x, y, z);
            set(hsurf,'FaceColor',[0 0 1],'FaceAlpha',0.5);
            hold( app.UIAxes, 'on' );
            [y z] = meshgrid(0:0.5:value2,0:0.5:value3);
            x = 0.*z + 0.*y;
            hsurf = surf(app.UIAxes, x, y, z);
            set(hsurf,'FaceColor',[0.3010 0.7450 0.9330],'FaceAlpha',0.5);
            [x z] = meshgrid(0:0.5:value,0:0.5:value3);
            y = 0.*x + 0.*z;
            hsurf = surf(app.UIAxes, x, y, z);
            set(hsurf,'FaceColor',[0.3010 0.7450 0.9330],'FaceAlpha',0.5);
            set(app.UIAxes,'Xdir','reverse','Ydir','reverse')
            
            
            %add randomness!!!
            x = app.SpeakerEditField.Value;
            y = app.SpeakerPositionEditField2.Value;
            z = app.SpeakerPositionEditField3.Value;
            value = app.RandomCheckBox.Value;
            if value == 1  
                x2 = app.SpeakerRandomEditField.Value;
                y2 = app.SpeakerRandomEditField2.Value;
                z2 = app.SpeakerRandomEditField3.Value;
                %fill 3d
                xCalc = [x, x2, x2, x, x, x2, x2, x];
                yCalc = [y, y, y2, y2, y, y, y2, y2];
                zCalc = [z, z, z, z, z2, z2, z2, z2];
                %bottom
                scat(1) = fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'red');
                %up
                fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'red');
                %right
                fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'red');
                %front
                fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'red');
                %back
                fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'red');
                %left
                fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'red');  
            else
                scat(1) = scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'red', 'MarkerEdgeColor', 'black');
            end
            
            %add randomness!!!
            x = app.Noise1EditField.Value;
            y = app.NoiseEditField2.Value;
            z = app.NoiseEditField3.Value;
            value = app.RandomCheckBox1.Value;
            if value == 1  
                x2 = app.Noise1RandomEditField.Value;
                y2 = app.Noise1RandomEditField2.Value;
                z2 = app.Noise1RandomEditField3.Value;
                %fill 3d
                xCalc = [x, x2, x2, x, x, x2, x2, x];
                yCalc = [y, y, y2, y2, y, y, y2, y2];
                zCalc = [z, z, z, z, z2, z2, z2, z2];
                %bottom
                scat(2) = fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'magenta');
                %up
                fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'magenta');
                %right
                fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'magenta');
                %front
                fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'magenta');
                %back
                fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'magenta');
                %left
                fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'magenta');  
            else
                scat(2) = scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'magenta', 'MarkerEdgeColor', 'black');
            end
            
            num_noises = str2double(app.NumberofNoiseSourcesDropDown.Value);
            if num_noises > 1
                x = app.Noise2EditField.Value;
                y = app.Noise2EditField2.Value;
                z = app.Noise2EditField3.Value;
                value = app.RandomCheckBox2.Value;
                if value == 1  
                    x2 = app.Noise2RandomEditField.Value;
                    y2 = app.Noise2RandomEditField2.Value;
                    z2 = app.Noise2RandomEditField3.Value;
                    %fill 3d
                    xCalc = [x, x2, x2, x, x, x2, x2, x];
                    yCalc = [y, y, y2, y2, y, y, y2, y2];
                    zCalc = [z, z, z, z, z2, z2, z2, z2];
                    %bottom
                    fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'magenta');
                    %up
                    fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'magenta');
                    %right
                    fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'magenta');
                    %front
                    fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'magenta');
                    %back
                    fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'magenta');
                    %left
                    fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'magenta');  
                else
                    scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'magenta', 'MarkerEdgeColor', 'black');
                end
            end
            if num_noises > 2
                x = app.Noise3EditField.Value;
                y = app.Noise3EditField2.Value;
                z = app.Noise3EditField3.Value;
                value = app.RandomCheckBox3.Value;
                if value == 1  
                    x2 = app.Noise3RandomEditField.Value;
                    y2 = app.Noise3RandomEditField2.Value;
                    z2 = app.Noise3RandomEditField3.Value;
                    %fill 3d
                    xCalc = [x, x2, x2, x, x, x2, x2, x];
                    yCalc = [y, y, y2, y2, y, y, y2, y2];
                    zCalc = [z, z, z, z, z2, z2, z2, z2];
                    %bottom
                    fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'magenta');
                    %up
                    fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'magenta');
                    %right
                    fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'magenta');
                    %front
                    fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'magenta');
                    %back
                    fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'magenta');
                    %left
                    fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'magenta');  
                else
                    scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'magenta', 'MarkerEdgeColor', 'black');
                end
            end
            if num_noises > 3
                x = app.Noise4EditField.Value;
                y = app.Noise4EditField2.Value;
                z = app.Noise4EditField3.Value;
                value = app.RandomCheckBox4.Value;
                if value == 1  
                    x2 = app.Noise4RandomEditField.Value;
                    y2 = app.Noise4RandomEditField2.Value;
                    z2 = app.Noise4RandomEditField3.Value;
                    %fill 3d
                    xCalc = [x, x2, x2, x, x, x2, x2, x];
                    yCalc = [y, y, y2, y2, y, y, y2, y2];
                    zCalc = [z, z, z, z, z2, z2, z2, z2];
                    %bottom
                    fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'magenta');
                    %up
                    fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'magenta');
                    %right
                    fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'magenta');
                    %front
                    fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'magenta');
                    %back
                    fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'magenta');
                    %left
                    fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'magenta');  
                else
                    scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'magenta', 'MarkerEdgeColor', 'black');
                end
            end
            if num_noises > 4
                x = app.Noise5EditField.Value;
                y = app.Noise5EditField2.Value;
                z = app.Noise5EditField3.Value;
                value = app.RandomCheckBox5.Value;
                if value == 1  
                    x2 = app.Noise5RandomEditField.Value;
                    y2 = app.Noise5RandomEditField2.Value;
                    z2 = app.Noise5RandomEditField3.Value;
                    %fill 3d
                    xCalc = [x, x2, x2, x, x, x2, x2, x];
                    yCalc = [y, y, y2, y2, y, y, y2, y2];
                    zCalc = [z, z, z, z, z2, z2, z2, z2];
                    %bottom
                    fill3(app.UIAxes, xCalc(1:4), yCalc(1:4), zCalc(1:4), 'magenta');
                    %up
                    fill3(app.UIAxes, xCalc(5:8), yCalc(5:8), zCalc(5:8), 'magenta');
                    %right
                    fill3(app.UIAxes, xCalc([2 6 7 3]), yCalc([2 6 7 3]), zCalc([2 6 7 3]), 'magenta');
                    %front
                    fill3(app.UIAxes, xCalc([2 6 5 1]), yCalc([2 6 5 1]), zCalc([2 6 5 1]), 'magenta');
                    %back
                    fill3(app.UIAxes, xCalc([4 8 7 3]), yCalc([4 8 7 3]), zCalc([4 8 7 3]), 'magenta');
                    %left
                    fill3(app.UIAxes, xCalc([1 5 8 4]), yCalc([1 5 8 4]), zCalc([1 5 8 4]), 'magenta');  
                else
                    scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'magenta', 'MarkerEdgeColor', 'black');
                end
            end
            
            x = app.Microphone1EditField.Value;
            y = app.Microphone1PositionEditField2.Value;
            z = app.Microphone1PositionEditField3.Value;
            scat(3) = scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'black', 'MarkerEdgeColor', 'black');
            value = str2double(app.NumberofMicrophonesDropDown.Value);
            if value > 1
                x = app.Microphone2EditField.Value;
                y = app.Microphone2PositionEditField2.Value;
                z = app.Microphone2PositionEditField3.Value;
                scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'black', 'MarkerEdgeColor', 'black');
            end
            if value > 2
                x = app.Microphone3EditField.Value;
                y = app.Microphone3PositionEditField2.Value;
                z = app.Microphone3PositionEditField3.Value;
                scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'black', 'MarkerEdgeColor', 'black');
            end
            if value > 3
                x = app.Microphone4EditField.Value;
                y = app.Microphone4PositionEditField2.Value;
                z = app.Microphone4PositionEditField3.Value;
                scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'black', 'MarkerEdgeColor', 'black');
            end
            if value > 4
                x = app.Microphone5EditField.Value;
                y = app.Microphone5PositionEditField2.Value;
                z = app.Microphone5PositionEditField3.Value;
                scatter3(app.UIAxes, x, y, z, 'MarkerFaceColor', 'black', 'MarkerEdgeColor', 'black');
            end
            lgd = legend(app.UIAxes, [scat(1) scat(3) scat(2)], 'Speaker','Microphones', 'Noise');
            %if app.RandomCheckBox.Value == 1
            %    set([scat(1)], 'Visible','off');
            %end
            lgd.Location = 'southoutside';
            lgd.Orientation = 'horizontal';
            hold(app.UIAxes, 'off' );
        end
        
        %update microphones
        function updateMics(app)
            value = str2double(app.NumberofMicrophonesDropDown.Value);
            if value > 1
                app.Microphone2EditFieldLabel.Visible = 'on';
                app.Microphone2EditField.Visible = 'on';
                app.Microphone2PositionEditField2.Visible = 'on';
                app.Microphone2PositionEditField3.Visible = 'on';
            else
                app.Microphone2EditFieldLabel.Visible = 'off';
                app.Microphone2EditField.Visible = 'off';
                app.Microphone2PositionEditField2.Visible = 'off';
                app.Microphone2PositionEditField3.Visible = 'off';
            end
            if value > 2
                app.Microphone3EditFieldLabel.Visible = 'on';
                app.Microphone3EditField.Visible = 'on';
                app.Microphone3PositionEditField2.Visible = 'on';
                app.Microphone3PositionEditField3.Visible = 'on';
            else
                app.Microphone3EditFieldLabel.Visible = 'off';
                app.Microphone3EditField.Visible = 'off';
                app.Microphone3PositionEditField2.Visible = 'off';
                app.Microphone3PositionEditField3.Visible = 'off';
            end
            if value > 3
                app.Microphone4EditFieldLabel.Visible = 'on';
                app.Microphone4EditField.Visible = 'on';
                app.Microphone4PositionEditField2.Visible = 'on';
                app.Microphone4PositionEditField3.Visible = 'on';
            else
                app.Microphone4EditFieldLabel.Visible = 'off';
                app.Microphone4EditField.Visible = 'off';
                app.Microphone4PositionEditField2.Visible = 'off';
                app.Microphone4PositionEditField3.Visible = 'off';
            end
            if value > 4
                app.Microphone5EditFieldLabel.Visible = 'on';
                app.Microphone5EditField.Visible = 'on';
                app.Microphone5PositionEditField2.Visible = 'on';
                app.Microphone5PositionEditField3.Visible = 'on';
            else
                app.Microphone5EditFieldLabel.Visible = 'off';
                app.Microphone5EditField.Visible = 'off';
                app.Microphone5PositionEditField2.Visible = 'off';
                app.Microphone5PositionEditField3.Visible = 'off';
            end
        end
        %noise sources
        function updateNoiseSources(app)
            value = str2double(app.NumberofNoiseSourcesDropDown.Value);
            if value > 1
                app.Noise2EditFieldLabel.Visible = 'on';
                app.Noise2EditField.Visible = 'on';
                app.Noise2EditField2.Visible = 'on';
                app.Noise2EditField3.Visible = 'on';
                app.Noise2Probability.Visible = 'on';
                app.Noise2Amp.Visible = 'on';
                app.Noise2Path.Visible = 'on';
                app.RandomLabel2.Visible = 'on';
                app.RandomCheckBox2.Visible = 'on';
            else
                app.Noise2EditFieldLabel.Visible = 'off';
                app.Noise2EditField.Visible = 'off';
                app.Noise2EditField2.Visible = 'off';
                app.Noise2EditField3.Visible = 'off';
                app.Noise2Probability.Visible = 'off';
                app.Noise2Amp.Visible = 'off';
                app.Noise2Path.Visible = 'off';
                app.RandomLabel2.Visible = 'off';
                app.RandomCheckBox2.Visible = 'off';
            end
            if value > 2
                app.Noise3EditFieldLabel.Visible = 'on';
                app.Noise3EditField.Visible = 'on';
                app.Noise3EditField2.Visible = 'on';
                app.Noise3EditField3.Visible = 'on';
                app.Noise3Probability.Visible = 'on';
                app.Noise3Amp.Visible = 'on';
                app.Noise3Path.Visible = 'on';
                app.RandomLabel3.Visible = 'on';
                app.RandomCheckBox3.Visible = 'on';
            else
                app.Noise3EditFieldLabel.Visible = 'off';
                app.Noise3EditField.Visible = 'off';
                app.Noise3EditField2.Visible = 'off';
                app.Noise3EditField3.Visible = 'off';
                app.Noise3Probability.Visible = 'off';
                app.Noise3Amp.Visible = 'off';
                app.Noise3Path.Visible = 'off';
                app.RandomLabel3.Visible = 'off';
                app.RandomCheckBox3.Visible = 'off';
            end
            if value > 3
                app.Noise4EditFieldLabel.Visible = 'on';
                app.Noise4EditField.Visible = 'on';
                app.Noise4EditField2.Visible = 'on';
                app.Noise4EditField3.Visible = 'on';
                app.Noise4Probability.Visible = 'on';
                app.Noise4Amp.Visible = 'on';
                app.Noise4Path.Visible = 'on';
                app.RandomLabel4.Visible = 'on';
                app.RandomCheckBox4.Visible = 'on';
            else
                app.Noise4EditFieldLabel.Visible = 'off';
                app.Noise4EditField.Visible = 'off';
                app.Noise4EditField2.Visible = 'off';
                app.Noise4EditField3.Visible = 'off';
                app.Noise4Probability.Visible = 'off';
                app.Noise4Amp.Visible = 'off';
                app.Noise4Path.Visible = 'off';
                app.RandomLabel4.Visible = 'off';
                app.RandomCheckBox4.Visible = 'off';
            end
            if value > 4
                app.Noise5EditFieldLabel.Visible = 'on';
                app.Noise5EditField.Visible = 'on';
                app.Noise5EditField2.Visible = 'on';
                app.Noise5EditField3.Visible = 'on';
                app.Noise5Probability.Visible = 'on';
                app.Noise5Amp.Visible = 'on';
                app.Noise5Path.Visible = 'on';
                app.RandomLabel5.Visible = 'on';
                app.RandomCheckBox5.Visible = 'on';
            else
                app.Noise5EditFieldLabel.Visible = 'off';
                app.Noise5EditField.Visible = 'off';
                app.Noise5EditField2.Visible = 'off';
                app.Noise5EditField3.Visible = 'off';
                app.Noise5Probability.Visible = 'off';
                app.Noise5Amp.Visible = 'off';
                app.Noise5Path.Visible = 'off';
                app.RandomLabel5.Visible = 'off';
                app.RandomCheckBox5.Visible = 'off';
            end
        end
        %noise types
        function updateWhite(app)
            value = app.WhiteNoiseCheckBox.Value;
            if value == 1
                app.WhiteNoiseSNR.Visible = 'on';
                app.WhiteNoiseSNRLabel.Visible = 'on';
            else
                app.WhiteNoiseSNR.Visible = 'off';
                app.WhiteNoiseSNRLabel.Visible = 'off';
            end
        end
        function updatePink(app)
            value = app.PinkNoiseCheckBox.Value;
            if value == 1
                app.PinkNoiseSNR.Visible = 'on';
                app.PinkNoiseSNRLabel.Visible = 'on';
            else
                app.PinkNoiseSNR.Visible = 'off';
                app.PinkNoiseSNRLabel.Visible = 'off';
            end
        end
        function updateBrown(app)
            value = app.BrownianNoiseCheckBox.Value;
            if value == 1
                app.BrownianNoiseSNR.Visible = 'on';
                app.BrownianNoiseSNRLabel.Visible = 'on';
            else
                app.BrownianNoiseSNR.Visible = 'off';
                app.BrownianNoiseSNRLabel.Visible = 'off';
            end
        end
        
        %random
        function updateRandom(app)
            value = app.RandomCheckBox.Value;
            if value == 1
                app.SpeakerRandomEditField.Visible = 'on';
                app.SpeakerRandomEditField2.Visible = 'on';
                app.SpeakerRandomEditField3.Visible = 'on';
            else
                app.SpeakerRandomEditField.Visible = 'off';
                app.SpeakerRandomEditField2.Visible = 'off';
                app.SpeakerRandomEditField3.Visible = 'off';
            end
        end
        function updateRandom1(app)
            value = app.RandomCheckBox1.Value;
            if value == 1
                app.Noise1RandomEditField.Visible = 'on';
                app.Noise1RandomEditField2.Visible = 'on';
                app.Noise1RandomEditField3.Visible = 'on';
            else
                app.Noise1RandomEditField.Visible = 'off';
                app.Noise1RandomEditField2.Visible = 'off';
                app.Noise1RandomEditField3.Visible = 'off';
            end
        end
        function updateRandom2(app)
            value = app.RandomCheckBox2.Value;
            if value == 1
                app.Noise2RandomEditField.Visible = 'on';
                app.Noise2RandomEditField2.Visible = 'on';
                app.Noise2RandomEditField3.Visible = 'on';
            else
                app.Noise2RandomEditField.Visible = 'off';
                app.Noise2RandomEditField2.Visible = 'off';
                app.Noise2RandomEditField3.Visible = 'off';
            end
        end
        function updateRandom3(app)
            value = app.RandomCheckBox3.Value;
            if value == 1
                app.Noise3RandomEditField.Visible = 'on';
                app.Noise3RandomEditField2.Visible = 'on';
                app.Noise3RandomEditField3.Visible = 'on';
            else
                app.Noise3RandomEditField.Visible = 'off';
                app.Noise3RandomEditField2.Visible = 'off';
                app.Noise3RandomEditField3.Visible = 'off';
            end
        end
        function updateRandom4(app)
            value = app.RandomCheckBox4.Value;
            if value == 1
                app.Noise4RandomEditField.Visible = 'on';
                app.Noise4RandomEditField2.Visible = 'on';
                app.Noise4RandomEditField3.Visible = 'on';
            else
                app.Noise4RandomEditField.Visible = 'off';
                app.Noise4RandomEditField2.Visible = 'off';
                app.Noise4RandomEditField3.Visible = 'off';
            end
        end
        function updateRandom5(app)
            value = app.RandomCheckBox5.Value;
            if value == 1
                app.Noise5RandomEditField.Visible = 'on';
                app.Noise5RandomEditField2.Visible = 'on';
                app.Noise5RandomEditField3.Visible = 'on';
            else
                app.Noise5RandomEditField.Visible = 'off';
                app.Noise5RandomEditField2.Visible = 'off';
                app.Noise5RandomEditField3.Visible = 'off';
            end
        end
        function updateNamingConv(app)
            value = app.InputFilenameAsOutputFilename.Value;
            if value == 1
                app.NamingConventionLabel.Visible = 'on';
                app.NamingConv.Visible = 'on';
            else
                app.NamingConventionLabel.Visible = 'off';
                app.NamingConv.Visible = 'off';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateMics(app);
            updateNoiseSources(app);
            updateWhite(app);
            updatePink(app);
            updateBrown(app);
            updateRandom(app);
            updateRandom1(app);
            updateRandom2(app);
            updateRandom3(app);
            updateRandom4(app);
            updateRandom5(app);
            updateNamingConv(app);
            updateGUI(app);
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
        end

        % Value changed function: RoomDimensionsEditField
        function RoomDimensionsEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: RoomDimensionsEditField2
        function RoomDimensionsEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: RoomDimensionsEditField3
        function RoomDimensionsEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeakerEditField
        function SpeakerEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeakerPositionEditField2
        function SpeakerPositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeakerPositionEditField3
        function SpeakerPositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: NoiseEditField2
        function NoiseEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: NoiseEditField3
        function NoiseEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise1EditField
        function Noise1EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2EditField2
        function Noise2EditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2EditField3
        function Noise2EditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2EditField
        function Noise2EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3EditField2
        function Noise3EditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3EditField3
        function Noise3EditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3EditField
        function Noise3EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4EditField2
        function Noise4EditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4EditField3
        function Noise4EditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4EditField
        function Noise4EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone1PositionEditField2
        function Microphone1PositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone1PositionEditField3
        function Microphone1PositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone1EditField
        function Microphone1EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone2EditField
        function Microphone2EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone2PositionEditField2
        function Microphone2PositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone2PositionEditField3
        function Microphone2PositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Button pushed function: GenerateDatasetButton
        function GenerateDatasetButtonPushed(app, event)
            %paths
            paths{1} = [convertCharsToStrings(app.SpeakerPath.Value)];
            value = str2double(app.NumberofNoiseSourcesDropDown.Value);
            if value == 1
                paths{2} = [convertCharsToStrings(app.NoisePath.Value)];
                amplitudes = [app.NoiseAmp.Value; app.NoiseAmp_2.Value];
                noise_prob = [app.NoiseProbability.Value];
            elseif value == 2
                paths{2} = [convertCharsToStrings(app.NoisePath.Value), convertCharsToStrings(app.Noise2Path.Value)];
                amplitudes = [app.NoiseAmp.Value, app.Noise2Amp.Value; app.NoiseAmp_2.Value, app.Noise2Amp_2.Value];
                noise_prob = [app.NoiseProbability.Value, app.Noise2Probability.Value];
            elseif value == 3
                paths{2} = [convertCharsToStrings(app.NoisePath.Value), convertCharsToStrings(app.Noise2Path.Value), convertCharsToStrings(app.Noise3Path.Value)];
                amplitudes = [app.NoiseAmp.Value, app.Noise2Amp.Value, app.Noise3Amp.Value; app.NoiseAmp_2.Value, app.Noise2Amp_2.Value, app.Noise3Amp_2.Value];
                noise_prob = [app.NoiseProbability.Value, app.Noise2Probability.Value, app.Noise3Probability.Value];
            elseif value == 4
                paths{2} = [convertCharsToStrings(app.NoisePath.Value), convertCharsToStrings(app.Noise2Path.Value), convertCharsToStrings(app.Noise3Path.Value), convertCharsToStrings(app.Noise4Path.Value)];
                amplitudes = [app.NoiseAmp.Value, app.Noise2Amp.Value, app.Noise3Amp.Value, app.Noise4Amp.Value; app.NoiseAmp_2.Value, app.Noise2Amp_2.Value, app.Noise3Amp_2.Value, app.Noise4Amp_2.Value];
                noise_prob = [app.NoiseProbability.Value, app.Noise2Probability.Value, app.Noise3Probability.Value, app.Noise4Probability.Value];
            elseif value == 5
                paths{2} = [convertCharsToStrings(app.NoisePath.Value), convertCharsToStrings(app.Noise2Path.Value), convertCharsToStrings(app.Noise3Path.Value), convertCharsToStrings(app.Noise4Path.Value), convertCharsToStrings(app.Noise5Path.Value)];
                amplitudes = [app.NoiseAmp.Value, app.Noise2Amp.Value, app.Noise3Amp.Value, app.Noise4Amp.Value, app.Noise5Amp.Value; app.NoiseAmp_2.Value, app.Noise2Amp_2.Value, app.Noise3Amp_2.Value, app.Noise4Amp_2.Value, app.Noise5Amp_2.Value;];
                noise_prob = [app.NoiseProbability.Value, app.Noise2Probability.Value, app.Noise3Probability.Value, app.Noise4Probability.Value, app.Noise5Probability.Value];
            end
            paths{3} = [convertCharsToStrings(app.OutputPath.Value)];
            paths{4} = convertCharsToStrings(app.NamingConv.Value);
            
            room_dims = [app.RoomDimensionsEditField.Value, app.RoomDimensionsEditField2.Value, app.RoomDimensionsEditField3.Value];
            
            value = str2double(app.NumberofMicrophonesDropDown.Value);
            mic_pos(1,:) = [app.Microphone1EditField.Value, app.Microphone1PositionEditField2.Value, app.Microphone1PositionEditField3.Value];
            if value > 1
                mic_pos(2,:) = [app.Microphone2EditField.Value, app.Microphone2PositionEditField2.Value, app.Microphone2PositionEditField3.Value];
            end
            if value > 2
                mic_pos(3,:) = [app.Microphone3EditField.Value, app.Microphone3PositionEditField2.Value, app.Microphone3PositionEditField3.Value];
            end
            if value > 3
                mic_pos(4,:) = [app.Microphone4EditField.Value, app.Microphone4PositionEditField2.Value, app.Microphone4PositionEditField3.Value];
            end
            if value > 4
                mic_pos(5,:) = [app.Microphone5EditField.Value, app.Microphone5PositionEditField2.Value, app.Microphone5PositionEditField3.Value];
            end

            if app.RandomCheckBox.Value == 1
                source_pos = [app.SpeakerEditField.Value; app.SpeakerPositionEditField2.Value; app.SpeakerPositionEditField3.Value; 
                              app.SpeakerRandomEditField.Value; app.SpeakerRandomEditField2.Value; app.SpeakerRandomEditField3.Value];
            else
                source_pos = [app.SpeakerEditField.Value; app.SpeakerPositionEditField2.Value; app.SpeakerPositionEditField3.Value];
            end
            
            value = str2double(app.NumberofNoiseSourcesDropDown.Value);
            if app.RandomCheckBox1.Value == 1
                noise_pos{1} = [app.Noise1EditField.Value; app.NoiseEditField2.Value; app.NoiseEditField3.Value;
                              app.Noise1RandomEditField.Value; app.Noise1RandomEditField2.Value; app.Noise1RandomEditField3.Value];
            else
                noise_pos{1} = [app.Noise1EditField.Value; app.NoiseEditField2.Value; app.NoiseEditField3.Value];
            end
            if value > 1
                if app.RandomCheckBox2.Value == 1
                    noise_pos{2} = [app.Noise2EditField.Value; app.Noise2EditField2.Value; app.Noise2EditField3.Value;
                                  app.Noise2RandomEditField.Value; app.Noise2RandomEditField2.Value; app.Noise2RandomEditField3.Value];
                else
                    noise_pos{2} = [app.Noise2EditField.Value; app.Noise2EditField2.Value; app.Noise2EditField3.Value];
                end
            end
            if value > 2
                if app.RandomCheckBox3.Value == 1
                    noise_pos{3} = [app.Noise3EditField.Value; app.Noise3EditField2.Value; app.Noise3EditField3.Value;
                                  app.Noise3RandomEditField.Value; app.Noise3RandomEditField2.Value; app.Noise3RandomEditField3.Value];
                else
                    noise_pos{3} = [app.Noise3EditField.Value; app.Noise3EditField2.Value; app.Noise3EditField3.Value];
                end
                
            end
            if value > 3
                if app.RandomCheckBox4.Value == 1
                    noise_pos{4} = [app.Noise4EditField.Value; app.Noise4EditField2.Value; app.Noise4EditField3.Value;
                                  app.Noise4RandomEditField.Value; app.Noise4RandomEditField2.Value; app.Noise4RandomEditField3.Value];
                else
                    noise_pos{4} = [app.Noise4EditField.Value; app.Noise4EditField2.Value; app.Noise4EditField3.Value];
                end
            end
            if value > 4
                if app.RandomCheckBox5.Value == 1
                    noise_pos{5} = [app.Noise5EditField.Value; app.Noise5EditField2.Value; app.Noise5EditField3.Value;
                                  app.Noise5RandomEditField.Value; app.Noise5RandomEditField2.Value; app.Noise5RandomEditField3.Value];
                else
                    noise_pos{5} = [app.Noise5EditField.Value; app.Noise5EditField2.Value; app.Noise5EditField3.Value];
                end
            end
            
            positions{1} = source_pos;
            positions{2} = noise_pos;
            positions{3} = mic_pos;
            positions{4} = room_dims;
            
            noise_settings{1} = noise_prob;
            noise_settings{2} = amplitudes;
            
            noise_options = [app.WhiteNoiseCheckBox.Value, app.WhiteNoiseSNR.Value, app.PinkNoiseCheckBox.Value, app.PinkNoiseSNR.Value, app.BrownianNoiseCheckBox.Value, app.BrownianNoiseSNR.Value];
            
            Goal_freq = app.DesiredSamplingRate.Value;
            
            value = app.SpeechOrderCheckBox.Value;
            if value == 1
                paths{5} = [convertCharsToStrings(app.SpeechOrderTextBox.Value)];
            end
            
            AugmentDataset(app, paths, positions, noise_settings, noise_options, Goal_freq);
            clear mic_pos noise_pos;
        end

        % Value changed function: NumberofMicrophonesDropDown
        function NumberofMicrophonesDropDownValueChanged(app, event)
            updateMics(app);
            updateGUI(app);
        end

        % Value changed function: Microphone3PositionEditField2
        function Microphone3PositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone3PositionEditField3
        function Microphone3PositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone3EditField
        function Microphone3EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone4PositionEditField2
        function Microphone4PositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone4PositionEditField3
        function Microphone4PositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone4EditField
        function Microphone4EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone5PositionEditField2
        function Microphone5PositionEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone5PositionEditField3
        function Microphone5PositionEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Microphone5EditField
        function Microphone5EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5EditField
        function Noise5EditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5EditField2
        function Noise5EditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5EditField3
        function Noise5EditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: NumberofNoiseSourcesDropDown
        function NumberofNoiseSourcesDropDownValueChanged(app, event)
            updateNoiseSources(app);
            updateGUI(app);
        end

        % Value changed function: WhiteNoiseCheckBox
        function WhiteNoiseCheckBoxValueChanged(app, event)
            updateWhite(app);
        end

        % Value changed function: PinkNoiseCheckBox
        function PinkNoiseCheckBoxValueChanged(app, event)
            updatePink(app);
        end

        % Value changed function: BrownianNoiseCheckBox
        function BrownianNoiseCheckBoxValueChanged(app, event)
            updateBrown(app);
        end

        % Value changed function: RandomCheckBox
        function RandomCheckBoxValueChanged(app, event)
            updateRandom(app);
            updateGUI(app);
        end

        % Value changed function: RandomCheckBox1
        function RandomCheckBox1ValueChanged(app, event)
            updateRandom1(app);
            updateGUI(app);
        end

        % Value changed function: RandomCheckBox2
        function RandomCheckBox2ValueChanged(app, event)
            updateRandom2(app);
            updateGUI(app);
        end

        % Value changed function: RandomCheckBox3
        function RandomCheckBox3ValueChanged(app, event)
            updateRandom3(app);
            updateGUI(app);
        end

        % Value changed function: RandomCheckBox4
        function RandomCheckBox4ValueChanged(app, event)
            updateRandom4(app);
            updateGUI(app);
        end

        % Value changed function: RandomCheckBox5
        function RandomCheckBox5ValueChanged(app, event)
            updateRandom5(app);
            updateGUI(app);
        end

        % Value changed function: SpeakerRandomEditField
        function SpeakerRandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeakerRandomEditField2
        function SpeakerRandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeakerRandomEditField3
        function SpeakerRandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise1RandomEditField
        function Noise1RandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise1RandomEditField2
        function Noise1RandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise1RandomEditField3
        function Noise1RandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2RandomEditField
        function Noise2RandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2RandomEditField2
        function Noise2RandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise2RandomEditField3
        function Noise2RandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3RandomEditField
        function Noise3RandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3RandomEditField2
        function Noise3RandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise3RandomEditField3
        function Noise3RandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4RandomEditField
        function Noise4RandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4RandomEditField2
        function Noise4RandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise4RandomEditField3
        function Noise4RandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5RandomEditField
        function Noise5RandomEditFieldValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5RandomEditField2
        function Noise5RandomEditField2ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: Noise5RandomEditField3
        function Noise5RandomEditField3ValueChanged(app, event)
            updateGUI(app);
        end

        % Value changed function: SpeechOrderCheckBox
        function SpeechOrderCheckBoxValueChanged(app, event)
            value = app.SpeechOrderCheckBox.Value;
            if value == 1
                app.SpeechOrderTextBox.Visible = 'on';
            else
                app.SpeechOrderTextBox.Visible = 'off';
            end
        end

        % Value changed function: InputFilenameAsOutputFilename
        function InputFilenameAsOutputFilenameValueChanged(app, event)
            updateNamingConv(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoiseAugmentLabel = uilabel(app.UIFigure);
            app.NoiseAugmentLabel.Text = 'NoiseAugment';
            app.DatasetsPanel = uipanel(app.UIFigure);
            app.DatasetsPanel.Title = 'Datasets';
            app.SpeakerPositionEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerPositionEditField2.ValueChangedFcn = createCallbackFcn(app, @SpeakerPositionEditField2ValueChanged, true);
            app.SpeakerPositionEditField2.Value = 1.3;
            app.SpeakerPositionEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerPositionEditField3.ValueChangedFcn = createCallbackFcn(app, @SpeakerPositionEditField3ValueChanged, true);
            app.SpeakerPositionEditField3.Value = 1.5;
            app.NoiseEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.NoiseEditField2.ValueChangedFcn = createCallbackFcn(app, @NoiseEditField2ValueChanged, true);
            app.NoiseEditField2.Value = 2;
            app.NoiseEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.NoiseEditField3.ValueChangedFcn = createCallbackFcn(app, @NoiseEditField3ValueChanged, true);
            app.NoiseEditField3.Value = 1.5;
            app.Noise2EditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2EditField2.ValueChangedFcn = createCallbackFcn(app, @Noise2EditField2ValueChanged, true);
            app.Noise2EditField2.Value = 2.5;
            app.Noise2EditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2EditField3.ValueChangedFcn = createCallbackFcn(app, @Noise2EditField3ValueChanged, true);
            app.Noise2EditField3.Value = 1;
            app.Noise3EditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3EditField2.ValueChangedFcn = createCallbackFcn(app, @Noise3EditField2ValueChanged, true);
            app.Noise3EditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3EditField3.ValueChangedFcn = createCallbackFcn(app, @Noise3EditField3ValueChanged, true);
            app.Noise3EditField3.Value = 1;
            app.Noise4EditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4EditField2.ValueChangedFcn = createCallbackFcn(app, @Noise4EditField2ValueChanged, true);
            app.Noise4EditField2.Value = 1;
            app.Noise4EditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4EditField3.ValueChangedFcn = createCallbackFcn(app, @Noise4EditField3ValueChanged, true);
            app.Noise4EditField3.Value = 1;
            app.XmLabel_2 = uilabel(app.DatasetsPanel);
            app.XmLabel_2.Text = 'X (m)';
            app.YmLabel_2 = uilabel(app.DatasetsPanel);
            app.YmLabel_2.Text = 'Y (m)';
            app.ZmLabel_2 = uilabel(app.DatasetsPanel);
            app.ZmLabel_2.Text = 'Z (m)';
            app.SpeakerPath = uieditfield(app.DatasetsPanel, 'text');
            app.SpeakerPath.Value = 'C:/Users/colem/Desktop/RoomAug/libri/';
            app.PathLabel = uilabel(app.DatasetsPanel);
            app.PathLabel.Text = 'Path';
            app.NoisePath = uieditfield(app.DatasetsPanel, 'text');
            app.NoisePath.Value = 'C:/Users/colem/Desktop/RoomAug/noise/';
            app.Noise2Path = uieditfield(app.DatasetsPanel, 'text');
            app.Noise3Path = uieditfield(app.DatasetsPanel, 'text');
            app.Noise4Path = uieditfield(app.DatasetsPanel, 'text');
            app.ProbabilityLabel = uilabel(app.DatasetsPanel);
            app.ProbabilityLabel.Text = 'Probability';
            app.NoiseProbability = uieditfield(app.DatasetsPanel, 'numeric');
            app.NoiseProbability.Limits = [0 1];
            app.NoiseProbability.Value = 1;
            app.Noise2Probability = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2Probability.Limits = [0 1];
            app.Noise2Probability.Value = 0.5;
            app.Noise3Probability = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3Probability.Limits = [0 1];
            app.Noise3Probability.Value = 0.5;
            app.Noise4Probability = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4Probability.Limits = [0 1];
            app.Noise4Probability.Value = 0.5;
            app.AmplitudeNormalizedtoSpeakerLabel = uilabel(app.DatasetsPanel);
            app.AmplitudeNormalizedtoSpeakerLabel.Text = {'Amplitude '; '(Normalized '; 'to Speaker)'};
            app.NoiseAmp = uieditfield(app.DatasetsPanel, 'numeric');
            app.NoiseAmp.Limits = [0 1];
            app.NoiseAmp.Value = 0.6;
            app.Noise2Amp = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2Amp.Limits = [0 1];
            app.Noise2Amp.Value = 0.3;
            app.Noise3Amp = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3Amp.Limits = [0 1];
            app.Noise3Amp.Value = 0.3;
            app.Noise4Amp = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4Amp.Limits = [0 1];
            app.Noise4Amp.Value = 0.6;
            app.SpeakerEditFieldLabel = uilabel(app.DatasetsPanel);
            app.SpeakerEditFieldLabel.Text = 'Speaker';
            app.SpeakerEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerEditField.ValueChangedFcn = createCallbackFcn(app, @SpeakerEditFieldValueChanged, true);
            app.SpeakerEditField.Value = 1.15;
            app.Noise1EditFieldLabel = uilabel(app.DatasetsPanel);
            app.Noise1EditFieldLabel.Text = 'Noise 1';
            app.Noise1EditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise1EditField.ValueChangedFcn = createCallbackFcn(app, @Noise1EditFieldValueChanged, true);
            app.Noise1EditField.Value = 2;
            app.Noise2EditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2EditField.ValueChangedFcn = createCallbackFcn(app, @Noise2EditFieldValueChanged, true);
            app.Noise3EditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3EditField.ValueChangedFcn = createCallbackFcn(app, @Noise3EditFieldValueChanged, true);
            app.Noise3EditField.Value = 3;
            app.Noise4EditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4EditField.ValueChangedFcn = createCallbackFcn(app, @Noise4EditFieldValueChanged, true);
            app.Noise4EditField.Value = 3.5;
            app.Noise5EditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5EditField2.ValueChangedFcn = createCallbackFcn(app, @Noise5EditField2ValueChanged, true);
            app.Noise5EditField2.Value = 3.5;
            app.Noise5EditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5EditField3.ValueChangedFcn = createCallbackFcn(app, @Noise5EditField3ValueChanged, true);
            app.Noise5EditField3.Value = 1;
            app.Noise5Path = uieditfield(app.DatasetsPanel, 'text');
            app.Noise5Probability = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5Probability.Limits = [0 1];
            app.Noise5Probability.Value = 0.5;
            app.Noise5Amp = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5Amp.Limits = [0 1];
            app.Noise5Amp.Value = 0.6;
            app.Noise5EditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5EditField.ValueChangedFcn = createCallbackFcn(app, @Noise5EditFieldValueChanged, true);
            app.Noise5EditField.Value = 3.5;
            app.NumberofNoiseSourcesDropDownLabel = uilabel(app.DatasetsPanel);
            app.NumberofNoiseSourcesDropDownLabel.Text = 'Number of Noise Sources';
            app.NumberofNoiseSourcesDropDown = uidropdown(app.DatasetsPanel);
            app.NumberofNoiseSourcesDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NumberofNoiseSourcesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofNoiseSourcesDropDownValueChanged, true);
            app.NumberofNoiseSourcesDropDown.Value = '1';
            app.Noise2EditFieldLabel = uilabel(app.DatasetsPanel);
            app.Noise2EditFieldLabel.Text = 'Noise 2';
            app.Noise3EditFieldLabel = uilabel(app.DatasetsPanel);
            app.Noise3EditFieldLabel.Text = 'Noise 3';
            app.Noise4EditFieldLabel = uilabel(app.DatasetsPanel);
            app.Noise4EditFieldLabel.Text = 'Noise 4';
            app.Noise5EditFieldLabel = uilabel(app.DatasetsPanel);
            app.Noise5EditFieldLabel.Text = 'Noise 5';
            app.DesiredSamplingRateKHzLabel = uilabel(app.DatasetsPanel);
            app.DesiredSamplingRateKHzLabel.Text = 'Desired Sampling Rate (KHz)';
            app.DesiredSamplingRate = uieditfield(app.DatasetsPanel, 'numeric');
            app.DesiredSamplingRate.Value = 16;
            app.WhiteNoiseCheckBox = uicheckbox(app.DatasetsPanel);
            app.WhiteNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteNoiseCheckBoxValueChanged, true);
            app.WhiteNoiseCheckBox.Text = '';
            app.PinkNoiseCheckBox = uicheckbox(app.DatasetsPanel);
            app.PinkNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PinkNoiseCheckBoxValueChanged, true);
            app.PinkNoiseCheckBox.Text = '';
            app.WhiteNoiseEditFieldLabel = uilabel(app.DatasetsPanel);
            app.WhiteNoiseEditFieldLabel.Text = 'White Noise';
            app.BrownianNoiseEditFieldLabel = uilabel(app.DatasetsPanel);
            app.BrownianNoiseEditFieldLabel.Text = 'Pink Noise';
            app.WhiteNoiseSNR = uieditfield(app.DatasetsPanel, 'numeric');
            app.PinkNoiseSNR = uieditfield(app.DatasetsPanel, 'numeric');
            app.WhiteNoiseSNRLabel = uilabel(app.DatasetsPanel);
            app.WhiteNoiseSNRLabel.Text = 'SNR';
            app.PinkNoiseSNRLabel = uilabel(app.DatasetsPanel);
            app.PinkNoiseSNRLabel.Text = 'SNR';
            app.BrownianNoiseCheckBox = uicheckbox(app.DatasetsPanel);
            app.BrownianNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrownianNoiseCheckBoxValueChanged, true);
            app.BrownianNoiseCheckBox.Text = '';
            app.PinkNoiseEditFieldLabel = uilabel(app.DatasetsPanel);
            app.PinkNoiseEditFieldLabel.Text = 'Brownian Noise';
            app.BrownianNoiseSNR = uieditfield(app.DatasetsPanel, 'numeric');
            app.BrownianNoiseSNRLabel = uilabel(app.DatasetsPanel);
            app.BrownianNoiseSNRLabel.Text = 'SNR';
            app.SpeakerRandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerRandomEditField.ValueChangedFcn = createCallbackFcn(app, @SpeakerRandomEditFieldValueChanged, true);
            app.SpeakerRandomEditField.Value = 1.3;
            app.SpeakerRandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerRandomEditField2.ValueChangedFcn = createCallbackFcn(app, @SpeakerRandomEditField2ValueChanged, true);
            app.SpeakerRandomEditField2.Value = 1.3;
            app.SpeakerRandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.SpeakerRandomEditField3.ValueChangedFcn = createCallbackFcn(app, @SpeakerRandomEditField3ValueChanged, true);
            app.SpeakerRandomEditField3.Value = 1.3;
            app.Noise1RandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise1RandomEditField.ValueChangedFcn = createCallbackFcn(app, @Noise1RandomEditFieldValueChanged, true);
            app.Noise1RandomEditField.Value = 1.3;
            app.Noise1RandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise1RandomEditField2.ValueChangedFcn = createCallbackFcn(app, @Noise1RandomEditField2ValueChanged, true);
            app.Noise1RandomEditField2.Value = 1.3;
            app.Noise1RandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise1RandomEditField3.ValueChangedFcn = createCallbackFcn(app, @Noise1RandomEditField3ValueChanged, true);
            app.Noise1RandomEditField3.Value = 1.3;
            app.Noise2RandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2RandomEditField.ValueChangedFcn = createCallbackFcn(app, @Noise2RandomEditFieldValueChanged, true);
            app.Noise2RandomEditField.Value = 1.3;
            app.Noise2RandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2RandomEditField2.ValueChangedFcn = createCallbackFcn(app, @Noise2RandomEditField2ValueChanged, true);
            app.Noise2RandomEditField2.Value = 1.3;
            app.Noise2RandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2RandomEditField3.ValueChangedFcn = createCallbackFcn(app, @Noise2RandomEditField3ValueChanged, true);
            app.Noise2RandomEditField3.Value = 1.3;
            app.Noise3RandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3RandomEditField.ValueChangedFcn = createCallbackFcn(app, @Noise3RandomEditFieldValueChanged, true);
            app.Noise3RandomEditField.Value = 1.3;
            app.Noise3RandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3RandomEditField2.ValueChangedFcn = createCallbackFcn(app, @Noise3RandomEditField2ValueChanged, true);
            app.Noise3RandomEditField2.Value = 1.3;
            app.Noise3RandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3RandomEditField3.ValueChangedFcn = createCallbackFcn(app, @Noise3RandomEditField3ValueChanged, true);
            app.Noise3RandomEditField3.Value = 1.3;
            app.Noise4RandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4RandomEditField.ValueChangedFcn = createCallbackFcn(app, @Noise4RandomEditFieldValueChanged, true);
            app.Noise4RandomEditField.Value = 1.3;
            app.Noise4RandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4RandomEditField2.ValueChangedFcn = createCallbackFcn(app, @Noise4RandomEditField2ValueChanged, true);
            app.Noise4RandomEditField2.Value = 1.3;
            app.Noise4RandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4RandomEditField3.ValueChangedFcn = createCallbackFcn(app, @Noise4RandomEditField3ValueChanged, true);
            app.Noise4RandomEditField3.Value = 1.3;
            app.Noise5RandomEditField = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5RandomEditField.ValueChangedFcn = createCallbackFcn(app, @Noise5RandomEditFieldValueChanged, true);
            app.Noise5RandomEditField.Value = 1.3;
            app.Noise5RandomEditField2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5RandomEditField2.ValueChangedFcn = createCallbackFcn(app, @Noise5RandomEditField2ValueChanged, true);
            app.Noise5RandomEditField2.Value = 1.3;
            app.Noise5RandomEditField3 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5RandomEditField3.ValueChangedFcn = createCallbackFcn(app, @Noise5RandomEditField3ValueChanged, true);
            app.Noise5RandomEditField3.Value = 1.3;
            app.RandomCheckBox = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBoxValueChanged, true);
            app.RandomCheckBox.Text = '';
            app.RandomCheckBox1 = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox1.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBox1ValueChanged, true);
            app.RandomCheckBox1.Text = '';
            app.RandomCheckBox2 = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox2.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBox2ValueChanged, true);
            app.RandomCheckBox2.Text = '';
            app.RandomCheckBox3 = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox3.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBox3ValueChanged, true);
            app.RandomCheckBox3.Text = '';
            app.RandomCheckBox4 = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox4.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBox4ValueChanged, true);
            app.RandomCheckBox4.Text = '';
            app.RandomCheckBox5 = uicheckbox(app.DatasetsPanel);
            app.RandomCheckBox5.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBox5ValueChanged, true);
            app.RandomCheckBox5.Text = '';
            app.RandomLabel = uilabel(app.DatasetsPanel);
            app.RandomLabel.Text = 'Random';
            app.RandomLabel1 = uilabel(app.DatasetsPanel);
            app.RandomLabel1.Text = 'Random';
            app.RandomLabel2 = uilabel(app.DatasetsPanel);
            app.RandomLabel2.Text = 'Random';
            app.RandomLabel3 = uilabel(app.DatasetsPanel);
            app.RandomLabel3.Text = 'Random';
            app.RandomLabel4 = uilabel(app.DatasetsPanel);
            app.RandomLabel4.Text = 'Random';
            app.RandomLabel5 = uilabel(app.DatasetsPanel);
            app.RandomLabel5.Text = 'Random';
            app.MovingCheckBox = uicheckbox(app.DatasetsPanel);
            app.MovingCheckBox.Visible = 'off';
            app.MovingCheckBox.Text = '';
            app.MovingLabel = uilabel(app.DatasetsPanel);
            app.MovingLabel.Visible = 'off';
            app.MovingLabel.Text = 'Moving';
            app.MovingConfigPath = uieditfield(app.DatasetsPanel, 'text');
            app.MovingConfigPath.Visible = 'off';
            app.MovingConfigPath.Value = 'C:/Users/colem/Desktop/ssgibbs/send/librespeech_input/LibriSpeech/test/wav/';
            app.MovingConfigLabel = uilabel(app.DatasetsPanel);
            app.MovingConfigLabel.Visible = 'off';
            app.MovingConfigLabel.Text = 'Config File';
            app.SpeechOrderCheckBox = uicheckbox(app.DatasetsPanel);
            app.SpeechOrderCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeechOrderCheckBoxValueChanged, true);
            app.SpeechOrderCheckBox.Text = '';
            app.UsesourcetextfileinsteadofpathLabel = uilabel(app.DatasetsPanel);
            app.UsesourcetextfileinsteadofpathLabel.Text = {'Use source text file'; ' instead of path'};
            app.SpeechOrderTextBox = uieditfield(app.DatasetsPanel, 'text');
            app.SpeechOrderTextBox.Value = 'C:/Users/colem/Desktop/ssgibbs/src-test.txt';
            app.NoiseAmp_2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.NoiseAmp_2.Limits = [0 1];
            app.NoiseAmp_2.Value = 0.6;
            app.Noise2Amp_2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise2Amp_2.Limits = [0 1];
            app.Noise2Amp_2.Value = 0.3;
            app.Noise3Amp_2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise3Amp_2.Limits = [0 1];
            app.Noise3Amp_2.Value = 0.3;
            app.Noise4Amp_2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise4Amp_2.Limits = [0 1];
            app.Noise4Amp_2.Value = 0.6;
            app.Noise5Amp_2 = uieditfield(app.DatasetsPanel, 'numeric');
            app.Noise5Amp_2.Limits = [0 1];
            app.Noise5Amp_2.Value = 0.6;
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.GenerateDatasetButton = uibutton(app.OutputPanel, 'push');
            app.GenerateDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDatasetButtonPushed, true);
            app.GenerateDatasetButton.Text = 'Generate Dataset';
            app.OutputPath = uieditfield(app.OutputPanel, 'text');
            app.OutputPath.Value = 'C:/Users/colem/Desktop/RoomAug/output/';
            app.OutputPathLabel = uilabel(app.OutputPanel);
            app.OutputPathLabel.Text = 'Output Path';
            app.NamingConv = uieditfield(app.OutputPanel, 'text');
            app.NamingConv.Value = 'aug_noisy';
            app.NamingConventionLabel = uilabel(app.OutputPanel);
            app.NamingConventionLabel.Text = 'Naming Convention';
            app.ProgressTextAreaLabel = uilabel(app.OutputPanel);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.OutputPanel);
            app.ProgressTextArea.Editable = 'off';
            app.InputFilenameAsOutputFilenameLabel = uilabel(app.OutputPanel);
            app.InputFilenameAsOutputFilenameLabel.Text = {'Use input file names'; 'as output file names'};
            app.InputFilenameAsOutputFilename = uicheckbox(app.OutputPanel);
            app.InputFilenameAsOutputFilename.ValueChangedFcn = createCallbackFcn(app, @InputFilenameAsOutputFilenameValueChanged, true);
            app.InputFilenameAsOutputFilename.Text = '';
            app.VisualizationofRoomPanel = uipanel(app.UIFigure);
            app.VisualizationofRoomPanel.Title = 'Visualization of Room';
            app.UIAxes = uiaxes(app.VisualizationofRoomPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RoomConfigurationPanel = uipanel(app.UIFigure);
            app.RoomConfigurationPanel.Title = 'Room Configuration';
            app.XmLabel = uilabel(app.RoomConfigurationPanel);
            app.XmLabel.Text = 'X (m)';
            app.YmLabel = uilabel(app.RoomConfigurationPanel);
            app.YmLabel.Text = 'Y (m)';
            app.ZmLabel = uilabel(app.RoomConfigurationPanel);
            app.ZmLabel.Text = 'Z (m)';
            app.RoomDimensionsEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.RoomDimensionsEditField2.Limits = [1 100];
            app.RoomDimensionsEditField2.ValueChangedFcn = createCallbackFcn(app, @RoomDimensionsEditField2ValueChanged, true);
            app.RoomDimensionsEditField2.Value = 3.55;
            app.RoomDimensionsEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.RoomDimensionsEditField3.Limits = [1 100];
            app.RoomDimensionsEditField3.ValueChangedFcn = createCallbackFcn(app, @RoomDimensionsEditField3ValueChanged, true);
            app.RoomDimensionsEditField3.Value = 2.5;
            app.Microphone1PositionEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone1PositionEditField2.ValueChangedFcn = createCallbackFcn(app, @Microphone1PositionEditField2ValueChanged, true);
            app.Microphone1PositionEditField2.Value = 1.15;
            app.Microphone1PositionEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone1PositionEditField3.ValueChangedFcn = createCallbackFcn(app, @Microphone1PositionEditField3ValueChanged, true);
            app.Microphone1PositionEditField3.Value = 1.5;
            app.Microphone2PositionEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone2PositionEditField2.ValueChangedFcn = createCallbackFcn(app, @Microphone2PositionEditField2ValueChanged, true);
            app.Microphone2PositionEditField2.Value = 1;
            app.Microphone2PositionEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone2PositionEditField3.ValueChangedFcn = createCallbackFcn(app, @Microphone2PositionEditField3ValueChanged, true);
            app.Microphone2PositionEditField3.Value = 1.5;
            app.Microphone2EditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone2EditField.ValueChangedFcn = createCallbackFcn(app, @Microphone2EditFieldValueChanged, true);
            app.Microphone2EditField.Value = 1;
            app.Microphone3PositionEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone3PositionEditField2.ValueChangedFcn = createCallbackFcn(app, @Microphone3PositionEditField2ValueChanged, true);
            app.Microphone3PositionEditField2.Value = 3;
            app.Microphone3PositionEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone3PositionEditField3.ValueChangedFcn = createCallbackFcn(app, @Microphone3PositionEditField3ValueChanged, true);
            app.Microphone3PositionEditField3.Value = 1.5;
            app.Microphone3EditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.Microphone3EditFieldLabel.Text = 'Microphone 3';
            app.Microphone3EditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone3EditField.ValueChangedFcn = createCallbackFcn(app, @Microphone3EditFieldValueChanged, true);
            app.Microphone3EditField.Value = 3;
            app.Microphone4PositionEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone4PositionEditField2.ValueChangedFcn = createCallbackFcn(app, @Microphone4PositionEditField2ValueChanged, true);
            app.Microphone4PositionEditField2.Value = 3;
            app.Microphone4PositionEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone4PositionEditField3.ValueChangedFcn = createCallbackFcn(app, @Microphone4PositionEditField3ValueChanged, true);
            app.Microphone4PositionEditField3.Value = 1.5;
            app.Microphone4EditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.Microphone4EditFieldLabel.Text = 'Microphone 4';
            app.Microphone4EditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone4EditField.ValueChangedFcn = createCallbackFcn(app, @Microphone4EditFieldValueChanged, true);
            app.Microphone4EditField.Value = 1;
            app.Microphone5PositionEditField2 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone5PositionEditField2.ValueChangedFcn = createCallbackFcn(app, @Microphone5PositionEditField2ValueChanged, true);
            app.Microphone5PositionEditField2.Value = 1;
            app.Microphone5PositionEditField3 = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone5PositionEditField3.ValueChangedFcn = createCallbackFcn(app, @Microphone5PositionEditField3ValueChanged, true);
            app.Microphone5PositionEditField3.Value = 1.5;
            app.Microphone5EditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.Microphone5EditFieldLabel.Text = 'Microphone 5';
            app.Microphone5EditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone5EditField.ValueChangedFcn = createCallbackFcn(app, @Microphone5EditFieldValueChanged, true);
            app.Microphone5EditField.Value = 3;
            app.Microphone2EditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.Microphone2EditFieldLabel.Text = 'Microphone 2';
            app.RoomDimensionsEditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.RoomDimensionsEditFieldLabel.Text = 'Room Dimensions';
            app.RoomDimensionsEditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.RoomDimensionsEditField.Limits = [1 100];
            app.RoomDimensionsEditField.ValueChangedFcn = createCallbackFcn(app, @RoomDimensionsEditFieldValueChanged, true);
            app.RoomDimensionsEditField.Value = 4.45;
            app.Microphone1EditFieldLabel = uilabel(app.RoomConfigurationPanel);
            app.Microphone1EditFieldLabel.Text = 'Microphone 1';
            app.Microphone1EditField = uieditfield(app.RoomConfigurationPanel, 'numeric');
            app.Microphone1EditField.ValueChangedFcn = createCallbackFcn(app, @Microphone1EditFieldValueChanged, true);
            app.Microphone1EditField.Value = 1;
            app.NumberofMicrophonesDropDownLabel = uilabel(app.RoomConfigurationPanel);
            app.NumberofMicrophonesDropDownLabel.Text = 'Number of Microphones';
            app.NumberofMicrophonesDropDown = uidropdown(app.RoomConfigurationPanel);
            app.NumberofMicrophonesDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NumberofMicrophonesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofMicrophonesDropDownValueChanged, true);
            app.NumberofMicrophonesDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end